classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RetinalVesselSegmentationLabel  matlab.ui.control.Label
        GeneratesegmentationButton      matlab.ui.control.Button
        UploadMaskImageButton           matlab.ui.control.Button
        UploadEyeImageButton            matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadEyeImageButton
        function UploadEyeImageButtonPushed(app, event)
            global main_image;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            drawnow;
            figure(app.UIFigure);
            filename=strcat(pathname,filename);
            main_image=imread(filename);
            imshow(main_image,'Parent',app.UIAxes);
        end

        % Button pushed function: UploadMaskImageButton
        function UploadMaskImageButtonPushed(app, event)
            global mask;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            drawnow;
            figure(app.UIFigure);
            filename=strcat(pathname,filename);
            mask= imread(filename);
        end

        % Button pushed function: GeneratesegmentationButton
        function GeneratesegmentationButtonPushed(app, event)
                global main_image;
                global mask;
                segmented_image=GetSegmentation(main_image,mask);
                imshow(segmented_image,'Parent',app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Segmented Image')
            zlabel(app.UIAxes_2, 'Z')
            app.UploadEyeImageButton = uibutton(app.UIFigure, 'push');
            app.UploadEyeImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEyeImageButtonPushed, true);
            app.UploadEyeImageButton.Text = 'Upload Eye Image';
            app.UploadMaskImageButton = uibutton(app.UIFigure, 'push');
            app.UploadMaskImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMaskImageButtonPushed, true);
            app.UploadMaskImageButton.Text = 'Upload Mask Image';
            app.GeneratesegmentationButton = uibutton(app.UIFigure, 'push');
            app.GeneratesegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratesegmentationButtonPushed, true);
            app.GeneratesegmentationButton.Text = 'Generate segmentation';
            app.RetinalVesselSegmentationLabel = uilabel(app.UIFigure);
            app.RetinalVesselSegmentationLabel.Text = 'Retinal Vessel Segmentation ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end